classdef final2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResetButton                 matlab.ui.control.Button
        ColorDropDown               matlab.ui.control.DropDown
        ColorDropDownLabel          matlab.ui.control.Label
        FractalGeneratorChaosLabel  matlab.ui.control.Label
        DividingFactorSlider        matlab.ui.control.Slider
        DividingFactorSliderLabel   matlab.ui.control.Label
        NoofIterationsSpinner       matlab.ui.control.Spinner
        NoofIterationsSpinnerLabel  matlab.ui.control.Label
        VertexKnob                  matlab.ui.control.DiscreteKnob
        VertexKnobLabel             matlab.ui.control.Label
        StartButton_2               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        no_of_iteration = 50000;        
        no_of_vertex = 3;
        d = 2;
        color = 'blue';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: VertexKnob
        function VertexKnobValueChanged(app, event)
            value = app.VertexKnob.Value;
            app.no_of_vertex = str2double(value);
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            N= app.no_of_iteration;
            v=zeros(N,2);
            a= app.no_of_vertex;
            t=linspace(0,2*pi,a+1);
            c=[cos(t);sin(t)]';
            p=[0,0];            
            for i=1:N
                idx=randi(a);
                s=c(idx,:);
                p=(p+s)/app.d;
                v(i,:)=p;
            end
            plot(app.UIAxes, v(:,1),v(:,2),'.',markersize=1, Color = app.color)
        end

        % Value changed function: NoofIterationsSpinner
        function NoofIterationsSpinnerValueChanged(app, event)
            app.no_of_iteration = app.NoofIterationsSpinner.Value;            
        end

        % Value changed function: DividingFactorSlider
        function DividingFactorSliderValueChanged(app, event)
            app.d = app.DividingFactorSlider.Value;            
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            app.color = app.ColorDropDown.Value;            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes)
            app.no_of_iteration = 50000;        
            app.no_of_vertex = 3;
            app.d = 2;
            app.color = 'blue';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'cool')
            app.StartButton_2 = uibutton(app.UIFigure, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Icon = fullfile(pathToMLAPP, 'start.jpg');
            app.StartButton_2.Text = 'Start';
            app.VertexKnobLabel = uilabel(app.UIFigure);
            app.VertexKnobLabel.Text = {'Vertex'; ''};
            app.VertexKnob = uiknob(app.UIFigure, 'discrete');
            app.VertexKnob.Items = {'3', '4', '5', '6', '7', '8', '9', '10', '999'};
            app.VertexKnob.ValueChangedFcn = createCallbackFcn(app, @VertexKnobValueChanged, true);
            app.VertexKnob.Value = '3';
            app.NoofIterationsSpinnerLabel = uilabel(app.UIFigure);
            app.NoofIterationsSpinnerLabel.Text = 'No of Iterations';
            app.NoofIterationsSpinner = uispinner(app.UIFigure);
            app.NoofIterationsSpinner.Step = 10000;
            app.NoofIterationsSpinner.Limits = [2000 Inf];
            app.NoofIterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofIterationsSpinnerValueChanged, true);
            app.NoofIterationsSpinner.Value = 50000;
            app.DividingFactorSliderLabel = uilabel(app.UIFigure);
            app.DividingFactorSliderLabel.Text = 'Dividing Factor';
            app.DividingFactorSlider = uislider(app.UIFigure);
            app.DividingFactorSlider.Limits = [1 4];
            app.DividingFactorSlider.ValueChangedFcn = createCallbackFcn(app, @DividingFactorSliderValueChanged, true);
            app.DividingFactorSlider.Value = 2;
            app.FractalGeneratorChaosLabel = uilabel(app.UIFigure);
            app.FractalGeneratorChaosLabel.Text = 'Fractal Generator (Chaos)';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'red', 'blue', 'green', 'cyan', 'magenta', 'yellow', 'black'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'red';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end